classdef SignalSourceController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LogMenu                      matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        ClearlogMenu                 matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        DetectButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        ChooseresDropDown            matlab.ui.control.DropDown
        ChooseresDropDownLabel       matlab.ui.control.Label
        SnapshotButton               matlab.ui.control.Button
        DetectionrateEditField       matlab.ui.control.NumericEditField
        DetectionrateEditFieldLabel  matlab.ui.control.Label
        StartlivedetectionButton     matlab.ui.control.Button
        MaxsizeEditField             matlab.ui.control.NumericEditField
        MaxsizeEditFieldLabel        matlab.ui.control.Label
        MinsizeEditField             matlab.ui.control.NumericEditField
        MinsizeEditFieldLabel        matlab.ui.control.Label
        ChoosecamDropDown            matlab.ui.control.DropDown
        ChoosecamDropDownLabel       matlab.ui.control.Label
        BurstSwitch                  matlab.ui.control.Switch
        BurstSwitchLabel             matlab.ui.control.Label
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        N_cycleEditField             matlab.ui.control.NumericEditField
        N_cycleEditFieldLabel        matlab.ui.control.Label
        PeriodEditField              matlab.ui.control.NumericEditField
        PeriodEditFieldLabel         matlab.ui.control.Label
        RatioEditField               matlab.ui.control.NumericEditField
        RatioEditFieldLabel          matlab.ui.control.Label
        V_minEditField               matlab.ui.control.NumericEditField
        V_minEditFieldLabel          matlab.ui.control.Label
        V_maxEditField               matlab.ui.control.NumericEditField
        V_maxEditFieldLabel          matlab.ui.control.Label
        StartcamButton               matlab.ui.control.Button
        AcquirecamlistButton         matlab.ui.control.Button
        SavelogButton                matlab.ui.control.Button
        Output1offButton             matlab.ui.control.Button
        Output1onButton              matlab.ui.control.Button
        sLabel_2                     matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        PulsesNCycleBurstsSettingsLabel  matlab.ui.control.Label
        SetButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        currentAppVersion = "v1.2";
        log % String
        isBurst = 1;
        camList = webcamlist;
        cam;
        res;
        ImageHandle;
        latestFrame;
        isStreaming = false;
        isProcessing = false;
        Timer;
        ProcessTimer;
        SnapshotHandle;
        OverlayHandle;
        RectHandles;
        isCellDetection = false;
        frameForDetect;
        DetectionRate = 1/4;
        minCellSize=500;
        maxCellSize=7000;
        se1;
        se2;
        combined_edge;
        W = cell(1,8);
        processedFrame;
    end
    
    methods (Access = public)
        function updateFrame(app)
            if app.isStreaming
                try
                    frame = snapshot(app.cam);
                    if ~isempty(frame)
                        app.ImageHandle.CData = frame;
                        drawnow limitrate; % More efficient update
                        
                        % Store the latest frame for processing
                        app.latestFrame = frame;
                    end
                catch ME
                    % Handle any camera errors silently
                    % warning('Camera error: %s', ME.message);
                end
            end
        end
        function dispLogInfo(app, str)
            string = sprintf("-> %s\n", str);
            app.log = strcat(app.log, string);
            app.LogTextArea.Value = app.log;
        end
        
        function processFrame(app)
            if app.isStreaming && app.isProcessing
                % Get the latest frame from the stored property
                frame = app.latestFrame;
                if isempty(frame)
                    return;
                end
               
                % Move data to GPU
                try 
                    if gpuDeviceCount > 0
                        frame_gpu = gpuArray(frame);
                        
                        % 图像处理（使用GPU加速）
                        GrayImage = rgb2gray(frame_gpu);
                        DoubleImage = im2single(GrayImage);
                        DenoisedImage = imfilter(DoubleImage, gpuArray(fspecial('gaussian', [3 3], 2)), 'replicate');
    
                        % Thresholding using direct comparison
                        frame_blue = frame_gpu(:,:,3);
                        M = graythresh(gather(frame_blue));  % graythresh needs CPU data
                        BinaryImage = frame_blue > (M * 255);  % Direct threshold comparison on GPU
                        BinaryImage = ~BinaryImage;
                        
                        DenoisedImage_cpu = gather(DenoisedImage);
                        ROIedge = edge(DenoisedImage_cpu,'canny', 0.3) | ...
                                 edge(DenoisedImage_cpu,'sobel') | ...
                                 edge(DenoisedImage_cpu,'prewitt') | ...
                                 edge(DenoisedImage_cpu,'roberts');
                        ROIedge = gpuArray(ROIedge);
    
                        % Move results back to CPU for further processing
                        DenoisedImage = gather(DenoisedImage);
                        BinaryImage = gather(BinaryImage);
                        ROIedge = gather(ROIedge);
                    else
                        % Fallback to CPU processing
                        GrayImage = rgb2gray(frame);
                        DoubleImage = im2single(GrayImage);
                        DenoisedImage = imfilter(DoubleImage, fspecial('gaussian', [3 3], 2), 'replicate');
                        M = graythresh(frame(:,:,3));
                        BinaryImage = frame(:,:,3) > (M * 255);  % Direct threshold comparison on CPU
                        BinaryImage = ~BinaryImage;
                        ROIedge = edge(DenoisedImage,'canny', 0.3) | ...
                                 edge(DenoisedImage,'sobel') | ...
                                 edge(DenoisedImage,'prewitt') | ...
                                 edge(DenoisedImage,'roberts');
                    end
                     
                    % Move Kirsch filters to GPU if available
                    if gpuDeviceCount > 0
                        app.W = cellfun(@gpuArray, app.W, 'UniformOutput', false);
                        DenoisedImage_gpu = gpuArray(DenoisedImage);
                    end
    
                    I = cell(1,8);
                    I9 = false(size(DenoisedImage));
    
                    parfor hh=1:8
                        if gpuDeviceCount > 0
                            I{1,hh} = filter2(app.W{1,hh}, DenoisedImage_gpu);
                            I{1,hh} = I{1,hh} > (graythresh(gather(I{1,hh})) * 255);  % Direct threshold comparison on GPU
                            I{1,hh} = gather(I{1,hh});
                        else
                            I{1,hh} = filter2(app.W{1,hh}, DenoisedImage);
                            I{1,hh} = I{1,hh} > (graythresh(I{1,hh}) * 255);  % Direct threshold comparison on CPU
                        end
                    end
    
                    I9 = I{1,1} | I{1,2} | I{1,3} | I{1,4} | I{1,5} | I{1,6} | I{1,7} | I{1,8};
                    I9 = imerode(I9, app.se1);
                    I9 = imclose(I9, app.se1);
                    
                    ROIseg = ROIedge | I9 | BinaryImage;
                    Finalseg = imclose(ROIseg, app.se2);
                    Finalseg = bwmorph(Finalseg, 'remove');
                    Finalseg = imfill(Finalseg, 4, 'holes');
                    Finalseg = bwareaopen(Finalseg, 100);
                    Finalseg = bwmorph(Finalseg, 'spur', 8);
                    Finalseg = bwmorph(Finalseg, 'clean');
                    
                    % 标记连通区域
                    [L_BW,NUM] = bwlabel(Finalseg, 4);
                    if NUM > 0
                        stats = regionprops(L_BW, 'Area', 'Perimeter', 'PixelList');
                        % 清除之前的矩形
                        if ~isempty(app.RectHandles)
                            delete(app.RectHandles);
                            app.RectHandles = [];
                        end
                    
                        % 绘制新的矩形
                        app.RectHandles = gobjects(NUM, 1);
                        hold(app.UIAxes, 'on');
                        rect_count = 0;
                        
                        for i = 1:NUM
                            S = stats(i).Area;
                            L = stats(i).Perimeter;
                            C = (L*L) / (4 * pi * S);
                            
                            if app.minCellSize<S && S<app.maxCellSize && 50<L && L<500 && 0.8<C && C<1.8
                                pixels = stats(i).PixelList;
                                rmin = min(pixels(:,2)); rmax = max(pixels(:,2));
                                cmin = min(pixels(:,1)); cmax = max(pixels(:,1));
                                w = cmax-cmin+1;
                                h = rmax-rmin+1;
                                
                                if (h>w && 1.5*w>h) || (w>h && 1.5*h>w) || (h==w)
                                    rect_count = rect_count + 1;
                                    app.RectHandles(rect_count) = rectangle(app.UIAxes, ...
                                        'Position', [cmin rmin w h], ...
                                        'EdgeColor', 'g', 'LineWidth', 2);
                                end
                            end
                        end
                    end
    
                    % 删除未使用的句柄预分配
                    app.RectHandles(rect_count+1:end) = [];
                    hold(app.UIAxes, 'off');
                catch ME
                    dispLogInfo(app, "Error processing frame: " + ME.message);
                end
            end    
        end
    end
    
    methods (Access = private)
        
        % Helper function to combine edges
        function updateEdge(app, x)
            if ~isfield(app, 'combined_edge')
                app.combined_edge = x;
            else
                app.combined_edge = app.combined_edge | x;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.W{1} = [5,5,5; -3,0,-3; -3,-3,-3];
            app.W{2} = [5,5,-3; 5,0,-3; -3,-3,-3];
            app.W{3} = [5,-3,-3; 5,0,-3; 5,-3,-3];
            app.W{4} = [-3,-3,-3; 5,0,-3; 5,5,-3];
            app.W{5} = [-3,-3,-3; -3,0,-3; 5,5,5];
            app.W{6} = [-3,-3,-3; -3,0,5; -3,5,5];
            app.W{7} = [-3,-3,5; -3,0,5; -3,-3,5];
            app.W{8} = [-3,5,5; -3,0,5; -3,-3,-3];

            if isempty(gcp('nocreate'))
                parpool("Processes");  % Initialize parallel pool
            end
            if gpuDeviceCount == 0
                warning('No GPU detected. Running on CPU instead.');
            else
                gpu = gpuDevice(1);
                reset(gpu);
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            V_max = app.V_maxEditField.Value;
            V_min = app.V_minEditField.Value;
            Ratio = app.RatioEditField.Value;
            Period = app.PeriodEditField.Value;
            N_cycle = app.N_cycleEditField.Value;
            app.BurstSwitch.Value = 'On';
            formattedString = sprintf("Parameters set with:\nV_max=%.2fV\nV_min=%.2fV\nRatio=%.2f%%\nPeriod=%.3fs\nN_cycle=%d", V_max, V_min, Ratio, Period, N_cycle);
            dispLogInfo(app, formattedString);
            
            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            fprintf(DG2052, ':SOUR1:FUNC PULSE');
            fprintf(DG2052, ':SOUR1:VOLT:HIGH %f', V_max);
            fprintf(DG2052, ':SOUR1:VOLT:LOW %f', V_min);
            fprintf(DG2052, ':SOUR1:FUNC:PULS:DCYC %f', Ratio);
            fprintf(DG2052, ':SOUR1:PER %f', Period);
            fprintf(DG2052, ':SOUR1:PHAS 0');
            fprintf(DG2052, ':SOUR1:BURS:STAT ON');
            fprintf(DG2052, ':SOUR1:BURS:IDLE 0');
            fprintf(DG2052, ':SOUR1:BURS:INT:PER 100');
            fprintf(DG2052, ':SOUR1:BURS:NCYC %f', N_cycle);
            fprintf(DG2052, ':SOUR1:BURS:MODE TRIG');
            fprintf(DG2052, ':SOUR1:BURS:TRIG:SOUR INT');
            fprintf(DG2052, ':SOUR1:BURS:TRIG:TRIGO OFF');
            fprintf(DG2052, ':SOUR1:BURS:TDEL 0.01');
            fclose(DG2052);
            clear DG2052;
        end

        % Button pushed function: Output1onButton
        function Output1onButtonPushed(app, event)
            dispLogInfo(app, "Output 1 ON.")
            app.Output1onButton.Enable = 'off';
            app.Output1offButton.Enable = 'on';
            
            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            fprintf(DG2052, ':OUTP1 ON');
            fclose(DG2052);
            clear DG2052;
        end

        % Button pushed function: Output1offButton
        function Output1offButtonPushed(app, event)
            dispLogInfo(app, "Output 1 OFF.")
            app.Output1onButton.Enable = 'on';

            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            fprintf(DG2052, ':OUTP1 OFF');
            fclose(DG2052);
            clear DG2052;
        end

        % Menu selected function: ClearlogMenu
        function ClearlogMenuSelected(app, event)
            app.log = "";
            app.LogTextArea.Value = "";
        end

        % Button pushed function: SavelogButton
        function SavelogButtonPushed(app, event)
            current_time = string(datetime('now', 'Format', 'yyyy-MM-dd''_''HH_mm_ss'));
            filename = sprintf('log-%s.txt',current_time);
            fileID = fopen(filename, 'w');
            fprintf(fileID, '%s', app.log);
            fclose(fileID);
        end

        % Value changed function: BurstSwitch
        function BurstSwitchValueChanged(app, event)
            value = app.BurstSwitch.Value;
            DG2052 = visa('NI', 'USB0::0x1AB1::0x0644::DG2P232400656::INSTR');
            fopen(DG2052);
            if value == "Off"
                app.isBurst = 0;
                dispLogInfo(app, "Burst OFF");
                fprintf(DG2052, ':SOUR1:BURS:STAT OFF');
            else
                app.isBurst = 1;
                dispLogInfo(app, "Burst ON");
                fprintf(DG2052, ':SOUR1:BURS:STAT ON');
            end
            fclose(DG2052);
            clear DG2052;
        end

        % Button pushed function: AcquirecamlistButton
        function AcquirecamlistButtonPushed2(app, event)
            app.camList = webcamlist;
            num_cam = length(app.camList);
            app.ChoosecamDropDown.Items = app.camList;
            app.cam = webcam(app.ChoosecamDropDown.Value);
            app.ChooseresDropDown.Items = app.cam.AvailableResolutions;
            app.res = app.ChooseresDropDown.Value;
            camString = sprintf("Camera list:\n");
            for i=1:num_cam
                camString = camString + app.camList(i) + sprintf(";\n");
            end
            dispLogInfo(app, camString);
        end

        % Button pushed function: StartcamButton
        function StartcamButtonPushed(app, event)
            app.isStreaming = ~app.isStreaming;
            if app.isStreaming
                app.StartcamButton.Text = "Stop cam";
                app.StartcamButton.FontColor = "#f54236";
                dispLogInfo(app, "Cam started.");
            else
                app.StartcamButton.Text = "Start cam";
                app.StartcamButton.FontColor = "#000000";
                dispLogInfo(app, "Cam stopped.");
            end

            if app.isStreaming
                width = str2double(app.res(1:strfind(app.res,'x')-1));
                height = str2double(app.res(strfind(app.res,'x')+1:end));
                if isempty(app.UIAxes.Children)
                    app.ImageHandle = image(app.UIAxes, zeros(width,height,3));
                end
                app.UIAxes.XLim = [0 width];
                app.UIAxes.YLim = [0 height];
                app.UIAxes.DataAspectRatio = [1 1 1];
                app.UIAxes.Visible = 'off';
                
                if isempty(app.Timer) || ~isvalid(app.Timer)
                    app.Timer = timer('ExecutionMode', 'fixedRate', ...
                        'Period', 1/50, ...  % Reduce to 30 Hz for better stability
                        'BusyMode', 'drop', ... % Drop frames if system is busy
                        'TimerFcn', @(~,~) updateFrame(app));
                end
                start(app.Timer);
            else
                if ~isempty(app.Timer) && isvalid(app.Timer)
                    stop(app.Timer);
                end
            end
        end

        % Value changed function: ChoosecamDropDown
        function ChoosecamDropDownValueChanged(app, event)
            value = app.ChoosecamDropDown.Value;
            app.cam = webcam(value);
            app.ChooseresDropDown.Items = app.cam.AvailableResolutions;
            app.res = app.ChooseresDropDown.Value;
            camChooseLogString = "Cam chosen:" + value;
            dispLogInfo(app, camChooseLogString);
        end

        % Value changed function: MinsizeEditField
        function MinsizeEditFieldValueChanged(app, event)
            value = app.MinsizeEditField.Value;
            app.minCellSize = value;
        end

        % Value changed function: MaxsizeEditField
        function MaxsizeEditFieldValueChanged2(app, event)
            value = app.MaxsizeEditField.Value;
            app.maxCellSize = value;
        end

        % Button pushed function: StartlivedetectionButton
        function StartlivedetectionButtonPushed(app, event)
            app.isProcessing = ~app.isProcessing;
            if app.isProcessing
                if isempty(gcp('nocreate'))
                    parpool('Processes');  % Initialize parallel pool
                end
                app.StartlivedetectionButton.Text = "Stop live detection";
                app.StartlivedetectionButton.FontColor = "#f54236";
                dispLogInfo(app, "Cell detection started.");             
                
                % 预创建结构元素
                app.se1 = strel('disk', 1);
                app.se2 = strel('disk', 3);

                if isempty(app.ProcessTimer) || ~isvalid(app.ProcessTimer)
                    app.ProcessTimer = timer('ExecutionMode', 'fixedRate', ...
                        'Period', app.DetectionRate, ...  % 按照DetectionRate频率处理
                        'TimerFcn', @(~,~) processFrame(app));
                end
                start(app.ProcessTimer);
            else
                app.StartlivedetectionButton.Text = "Start live detection";
                app.StartlivedetectionButton.FontColor = "#000000";
                dispLogInfo(app, "Cell detection stopped.");
                
                if ~isempty(app.ProcessTimer) && isvalid(app.ProcessTimer)
                    stop(app.ProcessTimer);
                    delete(app.ProcessTimer);
                end

                % Clean up GPU if it was used
                if gpuDeviceCount > 0
                    % Reset GPU device
                    gpuDevice(1);
                end

                % Clean up parallel pool asynchronously
                if ~isempty(gcp('nocreate'))
                    parfeval(@delete, 0, gcp('nocreate'));
                end

                % Clean up display
                if ~isempty(app.RectHandles)
                    delete(app.RectHandles);
                    app.RectHandles = [];
                end
            end
        end

        % Value changed function: DetectionrateEditField
        function DetectionrateEditFieldValueChanged(app, event)
            value = app.DetectionrateEditField.Value;
            app.DetectionRate = 1/value;
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            app.frameForDetect = snapshot(app.cam);
            dispLogInfo(app, "Snapshot captured.");
        end

        % Value changed function: ChooseresDropDown
        function ChooseresDropDownValueChanged(app, event)
            value = app.ChooseresDropDown.Value;
            app.res = value;
            camResLogString = "Cam resolution changed: " + app.res;
            dispLogInfo(app, camResLogString);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~exist('Snapshots', 'dir')
                mkdir('Snapshots');
            end
            current_time = datetime('now', 'Format', 'yyyy-MM-dd''_''HH_mm_ss');
            str0 = 'Snapshots\';
            str1 = sprintf('%s-Filter%d-DetectedImage',current_time,app.minCellSize);
            str2 = '.png';
            save_path = [str0,str1,str2];
            imwrite(app.processedFrame, save_path);
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            frame = app.frameForDetect;
            lw = cell(1,8);
            lW{1} = [5,5,5; -3,0,-3; -3,-3,-3];
            lW{2} = [5,5,-3; 5,0,-3; -3,-3,-3];
            lW{3} = [5,-3,-3; 5,0,-3; 5,-3,-3];
            lW{4} = [-3,-3,-3; 5,0,-3; 5,5,-3];
            lW{5} = [-3,-3,-3; -3,0,-3; 5,5,5];
            lW{6} = [-3,-3,-3; -3,0,5; -3,5,5];
            lW{7} = [-3,-3,5; -3,0,5; -3,-3,5];
            lW{8} = [-3,5,5; -3,0,5; -3,-3,-3];
            hh = 2;
            GrayImage = rgb2gray(frame);
            DoubleImage = im2double(GrayImage);
            h = fspecial('gaussian', [3 3], 2);
            DenoisedImage = imfilter(DoubleImage, h, 'replicate');
            M = graythresh(frame(:,:,3));
            BinaryImage = imbinarize(frame(:,:,3), M);
            BinaryImage = ~BinaryImage;
            ROIedge_canny = edge(DenoisedImage,'canny', 0.3);
            ROIedge_prewitt = edge(DenoisedImage,'prewitt');
            ROIedge_roberts = edge(DenoisedImage,'roberts');
            ROIedge_sobel = edge(DenoisedImage,'sobel');
            ROIedge = ROIedge_canny | ROIedge_prewitt | ROIedge_roberts | ROIedge_sobel;
            I = cell(1,8);
            for hh=1:8
                I{1,hh} = filter2(lW{1,hh}, DenoisedImage);
                I{1,hh} = imbinarize(I{1,hh}, graythresh(I{1,hh}));
            end
            I9 = I{1,1} | I{1,2} | I{1,3} | I{1,4} | I{1,5} | I{1,6} | I{1,7} | I{1,8};
            I9 = imerode(I9, app.se1);
            I9 = imclose(I9, app.se1);
            ROIseg = ROIedge | I9 | BinaryImage;
            Finalseg = imclose(ROIseg, app.se2);
            Finalseg = bwmorph(Finalseg, 'remove');
            Finalseg = imfill(Finalseg, 4, 'holes');
            Finalseg = bwareaopen(Finalseg, 100);
            Finalseg = bwmorph(Finalseg, 'spur', 8);
            Finalseg = bwmorph(Finalseg, 'clean');
            [L_BW,NUM] = bwlabel(Finalseg, 4);
            stats = regionprops(L_BW, 'all');
            figure;imshow(frame);
            for i=1:NUM
                [r,c] = find(L_BW==i);
                S(i) = stats(i).Area;
                L(i) = stats(i).Perimeter;
                C(i) = (L(i)*L(i)) / (4 * pi * S(i));
                if app.minCellSize<S(i) && S(i)<app.maxCellSize
                    if 50<L(i) && L(i)<500
                        if 0.8<C(i) && C(i)<1.8
                            rmin = min(r); rmax = max(r);
                            cmin = min(c); cmax = max(c);
                            w = cmax-cmin+1;
                            h = rmax-rmin+1;
                            hold on
                            if (h>w && 1.5*w>h) || (w>h && 1.5*h>w) || (h==w)
                                rectangle('Position', [cmin rmin w h]);
                                drawnow;
                            end
                        end
                    end
                end
            end
            app.processedFrame = getframe(gca).cdata;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            dispLogInfo(app, app.currentAppVersion);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LogMenu = uimenu(app.UIFigure);
            app.LogMenu.Text = 'Log';
            app.AboutMenu = uimenu(app.LogMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About.';
            app.ClearlogMenu = uimenu(app.LogMenu);
            app.ClearlogMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearlogMenuSelected, true);
            app.ClearlogMenu.Text = 'Clear log';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SetButton = uibutton(app.GridLayout, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.PulsesNCycleBurstsSettingsLabel = uilabel(app.GridLayout);
            app.PulsesNCycleBurstsSettingsLabel.Text = 'Pulses & N-Cycle Bursts Settings';
            app.VLabel = uilabel(app.GridLayout);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.GridLayout);
            app.VLabel_2.Text = 'V';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '%';
            app.sLabel_2 = uilabel(app.GridLayout);
            app.sLabel_2.Text = 's';
            app.Output1onButton = uibutton(app.GridLayout, 'push');
            app.Output1onButton.ButtonPushedFcn = createCallbackFcn(app, @Output1onButtonPushed, true);
            app.Output1onButton.Text = 'Output 1 on';
            app.Output1offButton = uibutton(app.GridLayout, 'push');
            app.Output1offButton.ButtonPushedFcn = createCallbackFcn(app, @Output1offButtonPushed, true);
            app.Output1offButton.Text = 'Output 1 off';
            app.SavelogButton = uibutton(app.GridLayout, 'push');
            app.SavelogButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogButtonPushed, true);
            app.SavelogButton.Text = 'Save log';
            app.AcquirecamlistButton = uibutton(app.GridLayout, 'push');
            app.AcquirecamlistButton.ButtonPushedFcn = createCallbackFcn(app, @AcquirecamlistButtonPushed2, true);
            app.AcquirecamlistButton.Text = 'Acquire camlist';
            app.StartcamButton = uibutton(app.GridLayout, 'push');
            app.StartcamButton.ButtonPushedFcn = createCallbackFcn(app, @StartcamButtonPushed, true);
            app.StartcamButton.Text = 'Start cam';
            app.V_maxEditFieldLabel = uilabel(app.GridLayout);
            app.V_maxEditFieldLabel.Text = 'V_max';
            app.V_maxEditField = uieditfield(app.GridLayout, 'numeric');
            app.V_maxEditField.Value = 1.5;
            app.V_minEditFieldLabel = uilabel(app.GridLayout);
            app.V_minEditFieldLabel.Text = 'V_min';
            app.V_minEditField = uieditfield(app.GridLayout, 'numeric');
            app.RatioEditFieldLabel = uilabel(app.GridLayout);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.GridLayout, 'numeric');
            app.RatioEditField.Value = 0.5;
            app.PeriodEditFieldLabel = uilabel(app.GridLayout);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.GridLayout, 'numeric');
            app.PeriodEditField.Value = 1;
            app.N_cycleEditFieldLabel = uilabel(app.GridLayout);
            app.N_cycleEditFieldLabel.Text = 'N_cycle';
            app.N_cycleEditField = uieditfield(app.GridLayout, 'numeric');
            app.N_cycleEditField.Value = 8;
            app.LogTextAreaLabel = uilabel(app.GridLayout);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.GridLayout);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Placeholder = 'Return Info & Logs';
            app.BurstSwitchLabel = uilabel(app.GridLayout);
            app.BurstSwitchLabel.Text = 'Burst';
            app.BurstSwitch = uiswitch(app.GridLayout, 'slider');
            app.BurstSwitch.ValueChangedFcn = createCallbackFcn(app, @BurstSwitchValueChanged, true);
            app.BurstSwitch.Value = 'On';
            app.ChoosecamDropDownLabel = uilabel(app.GridLayout);
            app.ChoosecamDropDownLabel.Text = 'Choose cam';
            app.ChoosecamDropDown = uidropdown(app.GridLayout);
            app.ChoosecamDropDown.Items = {};
            app.ChoosecamDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosecamDropDownValueChanged, true);
            app.ChoosecamDropDown.Placeholder = 'NULL.';
            app.ChoosecamDropDown.Value = {};
            app.MinsizeEditFieldLabel = uilabel(app.GridLayout);
            app.MinsizeEditFieldLabel.Text = 'Min size';
            app.MinsizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.MinsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinsizeEditFieldValueChanged, true);
            app.MinsizeEditField.Value = 500;
            app.MaxsizeEditFieldLabel = uilabel(app.GridLayout);
            app.MaxsizeEditFieldLabel.Text = 'Max size';
            app.MaxsizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxsizeEditFieldValueChanged2, true);
            app.MaxsizeEditField.Value = 7000;
            app.StartlivedetectionButton = uibutton(app.GridLayout, 'push');
            app.StartlivedetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartlivedetectionButtonPushed, true);
            app.StartlivedetectionButton.Text = 'Start live detection';
            app.DetectionrateEditFieldLabel = uilabel(app.GridLayout);
            app.DetectionrateEditFieldLabel.Text = 'Detection rate';
            app.DetectionrateEditField = uieditfield(app.GridLayout, 'numeric');
            app.DetectionrateEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionrateEditFieldValueChanged, true);
            app.DetectionrateEditField.Value = 4;
            app.SnapshotButton = uibutton(app.GridLayout, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
            app.ChooseresDropDownLabel = uilabel(app.GridLayout);
            app.ChooseresDropDownLabel.Text = 'Choose res';
            app.ChooseresDropDown = uidropdown(app.GridLayout);
            app.ChooseresDropDown.Items = {};
            app.ChooseresDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseresDropDownValueChanged, true);
            app.ChooseresDropDown.Placeholder = 'NULL.';
            app.ChooseresDropDown.Value = {};
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DetectButton = uibutton(app.GridLayout, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalSourceController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end